classdef ImageFeaturesAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageFeatureAnalysisUIFigure  matlab.ui.Figure
        PicturescataloguesPanel       matlab.ui.container.Panel
        ExtensionDropDownLabel        matlab.ui.control.Label
        ExtensionDropDown             matlab.ui.control.DropDown
        ChooseonepathButton           matlab.ui.control.Button
        ChoosetwopathsButton          matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        stimageEditFieldLabel         matlab.ui.control.Label
        stimageEditField              matlab.ui.control.NumericEditField
        ndimageEditFieldLabel         matlab.ui.control.Label
        ndimageEditField              matlab.ui.control.NumericEditField
        numberOfFiles1Label           matlab.ui.control.Label
        numberOfFiles2Label           matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        DescriptorsButtonGroup        matlab.ui.container.ButtonGroup
        SURFButton                    matlab.ui.control.ToggleButton
        BRISKButton                   matlab.ui.control.ToggleButton
        HOGButton                     matlab.ui.control.ToggleButton
        FASTButton                    matlab.ui.control.ToggleButton
        ORBButton                     matlab.ui.control.ToggleButton
        MSERButton                    matlab.ui.control.ToggleButton
        HARRISButton                  matlab.ui.control.ToggleButton
        ColormodelSwitchLabel         matlab.ui.control.Label
        ColormodelSwitch              matlab.ui.control.Switch
        CompareButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        GeneratereportButton          matlab.ui.control.Button
        SimiliaritythresholdLabel     matlab.ui.control.Label
        ThresholdLabel                matlab.ui.control.Label
        MoviesequencecreatorPanel     matlab.ui.container.Panel
        CreatesequenceButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        numberOfFiles;                        %number of all files
        numberOfFiles2;                       %number of all files from 2nd path
        secondPathIsChoosed = false;          %indicates if 2nd path is choosed
        extension = '.jpg'
        
        I;                                    %first image
        I2;                                   %second image
        IAll = struct('Image', []);           %Struct with all images
        IAll2 = struct('Image', []);          %struct with images from 2nd path
        
        currentImage1Number =1;
        currentImage2Number =2;
        myFolder;
        theFiles;
        
        colourVariantChangedBefore = false;  %Indicates if colour variant has been chnged before, to not generate G+B again
        selectedVariant = 'RGB'
        IAllGB = struct('Image', []);           %Struct with all images converted in GB
        IAll2GB = struct('Image', []);          %struct with images from 2nd path converted in GB
        
        
        selectedDescriptor = 'SURF';
        
        similarityThreshold = 1;
        
        mtchPoints1;
        mtchPoints2;
        
    end
    
    methods (Access = private)
        
        function I = rgb2gb(app, Image)
            I = Image;
            I(:,:,1) = 0;               %change chanel red to zeros
        end
        
        
        function I = uploadImages(app)
        % Specify the folder where the files live.
        app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
        % Check to make sure that folder actually exists.  Warn user if it doesn't.
        if ~isfolder(app.myFolder)
            errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
            uiwait(warndlg(errorMessage));
            return;
        end
        
        %get extension
        formatSpec = '*%1$s';
        str = app.extension;
        ext = sprintf(formatSpec,str);
        
        % Get a list of all files in the folder with the desired file name pattern.
        filePattern = fullfile(app.myFolder, ext); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
        app.theFiles = dir(filePattern);
        I(1:length(app.theFiles)) = struct('Image', []);
        app.numberOfFiles = length(app.theFiles);
        
        hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
        for k = 1: app.numberOfFiles
            
            file = fullfile(app.myFolder, app.theFiles(k).name);
            Image = imread(file);
            I(k).Image = Image;%Read the jth image            
            waitbar(k/app.numberOfFiles)
        end        
        delete(hWaitBar);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ExtensionDropDown
        function ExtensionDropDownValueChanged(app, event)
            value = app.ExtensionDropDown.Value;
            
            app.extension = value;
        end

        % Button pushed function: ChooseonepathButton
        function ChooseonepathButtonPushed(app, event)
        app.secondPathIsChoosed = false;
        
        app.IAll = uploadImages(app);
        
        app.I = app.IAll(1).Image;
        imshow(app.I,'Parent', app.UIAxes);
        app.I2 = app.IAll(2).Image;
        imshow(app.I2, 'Parent', app.UIAxes_2 );
        
        drawnow; % Force display to update immediately.
        app.stimageEditField.Value = 1;
        app.ndimageEditField.Value = 2;
        formatSpec = '/%d';
        app.numberOfFiles1Label.Text = sprintf(formatSpec,app.numberOfFiles);
        app.numberOfFiles2Label.Text = sprintf(formatSpec,app.numberOfFiles);
        
        app.ColormodelSwitch.Value = 'RGB';
        app.colourVariantChangedBefore = false;
        end

        % Button pushed function: ChoosetwopathsButton
        function ChoosetwopathsButtonPushed(app, event)
        app.secondPathIsChoosed = true;
        
        app.IAll = uploadImages(app);
        app.I = app.IAll(1).Image;
        imshow(app.I,'Parent', app.UIAxes);
        app.stimageEditField.Value = 1;
        
        % % % % % % % % % % % % % % % % % % % % % CHOOSING SECOND PATH % % % % % % % % % %
        
        app.currentImage2Number = 1;                                                                                %update number for file from 2nd path
        app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory');          %obsługa wyjątku kiedy nic nie wybierzemy??
        % Check to make sure that folder actually exists.  Warn user if it doesn't.
        if ~isfolder(app.myFolder)
            errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
            uiwait(warndlg(errorMessage));
            return;
        end
        % Get a list of all files in the folder with the desired file name pattern.
        filePattern = fullfile(app.myFolder, '*.jpg');                                                              %case do roznych wybranych rozszerzen
        app.theFiles = dir(filePattern);
        app.IAll2(1:length(app.theFiles)) = struct('Image', []);
        app.numberOfFiles2 = length(app.theFiles);
        
        hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
        for k = 1: app.numberOfFiles2
            
            file = fullfile(app.myFolder, app.theFiles(k).name);
            Image = imread(file);
            app.IAll2(k).Image = Image;       %Read the kth image
            
            waitbar(k/app.numberOfFiles)      %update waitbar
        end
        delete(hWaitBar);
        
        app.I2 = app.IAll2(1).Image;
        imshow(app.I2, 'Parent', app.UIAxes_2 );
        
        drawnow; % Force display to update immediately.
        
        app.ndimageEditField.Value = 1;
        formatSpec = '/%d';
        app.numberOfFiles1Label.Text = sprintf(formatSpec,app.numberOfFiles);
        app.numberOfFiles2Label.Text = sprintf(formatSpec,app.numberOfFiles2);
        
        app.ColormodelSwitch.Value = 'RGB';
        app.colourVariantChangedBefore = false;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
        app.currentImage1Number = app.currentImage1Number+1;
        app.currentImage2Number = app.currentImage2Number+1;
        if app.secondPathIsChoosed ==false                          %version with one path
            
            if app.currentImage2Number == app.numberOfFiles+1
                
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(1).Image;
                else
                    app.I = app.IAllGB(1).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = 1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll(2).Image;
                else
                    app.I2 = app.IAllGB(2).Image;
                end
                
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = 2;
                app.ndimageEditField.Value = app.currentImage2Number;
                
            else
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(app.currentImage1Number).Image;
                else
                    app.I = app.IAllGB(app.currentImage1Number).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                
                app.stimageEditField.Value = app.currentImage1Number;
                
                if strcmp('RGB', app.selectedVariant)
                app.I2 = app.IAll(app.currentImage2Number).Image;
                else
                    app.I2 = app.IAllGB(app.currentImage2Number).Image;
                end
                
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                
                app.ndimageEditField.Value = app.currentImage2Number;
            end
            
        elseif app.secondPathIsChoosed == true                          %version with two paths
            
            if app.currentImage1Number == app.numberOfFiles+1
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(1).Image;
                else
                    app.I = app.IAllGB(1).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = 1;
                app.stimageEditField.Value = app.currentImage1Number;    
            else
                if strcmp('RGB', app.selectedVariant)
                app.I = app.IAll(app.currentImage1Number).Image;
                else
                    app.I = app.IAllGB(app.currentImage1Number).Image;
                end
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                
                app.stimageEditField.Value = app.currentImage1Number;
            end
            if app.currentImage2Number == app.numberOfFiles2+1
                
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll2(1).Image;
                else
                    app.I2 = app.IAll2GB(1).Image;
                end
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = 1;
                app.ndimageEditField.Value = app.currentImage2Number;
            else
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll2(app.currentImage2Number).Image;
                else
                    app.I2 = app.IAll2GB(app.currentImage2Number).Image;
                end
                
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                
                app.ndimageEditField.Value = app.currentImage2Number;
            end                          
            
        end


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
        app.currentImage1Number = app.currentImage1Number-1;
        app.currentImage2Number = app.currentImage2Number-1;
        
        if app.secondPathIsChoosed == false
            if(app.currentImage1Number == 0)
                
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(app.numberOfFiles-1).Image;
                else
                    app.I = app.IAllGB(app.numberOfFiles-1).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = app.numberOfFiles-1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll(app.numberOfFiles).Image;
                else
                    app.I2 = app.IAllGB(app.numberOfFiles).Image;
                end
                
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.numberOfFiles;
                app.ndimageEditField.Value = app.currentImage2Number;
            else
                
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(app.currentImage1Number).Image;
                else
                    app.I = app.IAllGB(app.currentImage1Number).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                
                app.stimageEditField.Value = app.currentImage1Number;
                
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll(app.currentImage2Number).Image;
                else
                    app.I2 = app.IAllGB(app.currentImage2Number).Image;
                end
                
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                
                app.ndimageEditField.Value = app.currentImage2Number;
            end
            
        elseif app.secondPathIsChoosed == true
            if app.currentImage1Number == 0
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(app.numberOfFiles).Image;
                else
                    app.I = app.IAllGB(app.numberOfFiles).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = app.numberOfFiles;
                app.stimageEditField.Value = app.currentImage1Number;
            else
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(app.currentImage1Number).Image;
                else
                    app.I = app.IAllGB(app.currentImage1Number).Image;
                end
                
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                
                app.stimageEditField.Value = app.currentImage1Number;
            end    
            if app.currentImage2Number == 0
                
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll2(app.numberOfFiles2).Image;
                else
                    app.I2 = app.IAll2GB(app.numberOfFiles2).Image;
                end
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.numberOfFiles2;
                app.ndimageEditField.Value = app.currentImage2Number;
            else
                if strcmp('RGB', app.selectedVariant)
                app.I2 = app.IAll2(app.currentImage2Number).Image;
                else
                    app.I2 = app.IAll2GB(app.currentImage2Number).Image;
                end
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                
                app.ndimageEditField.Value = app.currentImage2Number;
                
            end
        end
        end

        % Value changed function: stimageEditField
        function stimageEditFieldValueChanged(app, event)
        value = app.stimageEditField.Value;

            if value > app.numberOfFiles || value < 1
                warndlg('Number is out of range.');
                return;
            end
            if value >= app.currentImage2Number && app.secondPathIsChoosed == false
                warndlg('1st image must be befor 2nd image')
                return;
            else
                if strcmp('RGB', app.selectedVariant)
                    app.I = app.IAll(value).Image;
                else
                    app.I = app.IAllGB(value).Image;
                end
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = value;
            end

        end

        % Value changed function: ndimageEditField
        function ndimageEditFieldValueChanged(app, event)
        value = app.ndimageEditField.Value;
        
        if app.secondPathIsChoosed == false
            if value > app.numberOfFiles || value < 1
                warndlg('Number is out of range.');
                return;
            end
            if value <= app.currentImage1Number
                warndlg('2nd image must be after 1st image')
                return;
            else
                if strcmp('RGB', app.selectedVariant)
                    app.I2 = app.IAll(value).Image;
                else
                    app.I2 = app.IAllGB(value).Image;
                end
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = value;
            end
            
        elseif app.secondPathIsChoosed == true
            
            if value > app.numberOfFiles2 || value < 1
                warndlg('Number is out of range.');
                return;     
                
            else
                if strcmp('RGB', app.selectedVariant)
                app.I2 = app.IAll2(value).Image;
                else
                    app.I2 = app.IAll2GB(value).Image;
                end
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = value;
            end                
        end
        end

        % Selection changed function: DescriptorsButtonGroup
        function DescriptorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DescriptorsButtonGroup.SelectedObject.Text;           
            app.selectedDescriptor = selectedButton;                                            
        end

        % Value changed function: ColormodelSwitch
        function ColormodelSwitchValueChanged(app, event)
        value = app.ColormodelSwitch.Value;
        app.selectedVariant = value;
        
        if app.colourVariantChangedBefore == false
            app.colourVariantChangedBefore = true;
            if app.secondPathIsChoosed == false
                
                hWaitBar = waitbar(0,'Creating files...','Name', 'Please wait');
                for i = 1:1:app.numberOfFiles
                    Image = app.IAll(i).Image;
                    
                    IGB = rgb2gb(app, Image);                    
                    app.IAllGB(i).Image = IGB;                    
                    waitbar(i/app.numberOfFiles)
                end
                delete(hWaitBar);
                
            else
                hWaitBar = waitbar(0,'Creating files from first path...','Name', 'Please wait');
                for i = 1:1:app.numberOfFiles
                    Image = app.IAll(i).Image;
                    
                    IGB = rgb2gb(app, Image);                                               
                    app.IAllGB(i).Image = IGB;                                                 
                    waitbar(i/app.numberOfFiles);
                end
                delete(hWaitBar);
                
                hWaitBar = waitbar(0,'Creating files from second path...','Name', 'Please wait');
                for i = 1:1:app.numberOfFiles2
                    Image = app.IAll2(i).Image;
                    
                    IGB = rgb2gb(app, Image);                    
                    app.IAll2GB(i).Image = IGB;                    
                    waitbar(i/app.numberOfFiles);
                end
                delete(hWaitBar);
                
            end
         end   
            
         if strcmp('G+B',app.selectedVariant)
             if app.secondPathIsChoosed == false
                 
                 app.I = app.IAllGB(app.currentImage1Number).Image;
                 imshow(app.I,'Parent', app.UIAxes);
                 app.I2 = app.IAllGB(app.currentImage2Number).Image;
                 imshow(app.I2, 'Parent', app.UIAxes_2 );
             else
                 app.I = app.IAllGB(app.currentImage1Number).Image;
                 imshow(app.I,'Parent', app.UIAxes);
                 app.I2 = app.IAll2GB(app.currentImage2Number).Image;
                 imshow(app.I2, 'Parent', app.UIAxes_2 );
             end
             
         else
             if app.secondPathIsChoosed == false
                 app.I = app.IAll(app.currentImage1Number).Image;
                 imshow(app.I,'Parent', app.UIAxes);
                 app.I2 = app.IAll(app.currentImage2Number).Image;
                 imshow(app.I2, 'Parent', app.UIAxes_2 );
             else
                 app.I = app.IAll(app.currentImage1Number).Image;
                 imshow(app.I,'Parent', app.UIAxes);
                 app.I2 = app.IAll2(app.currentImage2Number).Image;
                 imshow(app.I2, 'Parent', app.UIAxes_2 );
                 
             end
         end                   
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
        value = app.Slider.Value;
        app.similarityThreshold = round(value,2);
        
        formatSpec = '%.0f';
        str = sprintf(formatSpec,round(value,2));
        
        app.ThresholdLabel.Text = str;
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
        Im1gray=rgb2gray(app.I);
        Im2gray = rgb2gray(app.I2);
        
        switch app.selectedDescriptor
            case 'SURF'
                points1 = detectSURFFeatures(Im1gray);
                points2 = detectSURFFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold);
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                app.mtchPoints1 = matchedPoints1;
                app.mtchPoints2 = matchedPoints2;
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'SURF matched features');
                legend(ax, 'Matched points 1','Matched points 2');
            case 'HOG'
                points1 = detectFASTFeatures(Im1gray);
                points2 = detectFASTFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold) ;
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'HOG matched features');
                legend(ax, 'Matched points 1','Matched points 2');
            case 'BRISK'
                points1 = detectBRISKFeatures(Im1gray);
                points2 = detectBRISKFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold) ;
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'BRISK matched features');
                legend(ax, 'Matched points 1','Matched points 2');
                
            case 'FAST'
                points1 = detectFASTFeatures(Im1gray);
                points2 = detectFASTFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold) ;
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'FAST matched features');
                legend(ax, 'Matched points 1','Matched points 2');
                
            case 'ORB'
                points1 = detectORBFeatures(Im1gray);
                points2 = detectORBFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold);
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'ORB matched features');
                legend(ax, 'Matched points 1','Matched points 2');
                
            case 'MSER'
                points1 = detectMSERFeatures(Im1gray);
                points2 = detectMSERFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold) ;
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax, 'MSER matched features');
                legend(ax, 'Matched points 1','Matched points 2');
                
            case 'HARRIS'
                points1 = detectHarrisFeatures(Im1gray);
                points2 = detectHarrisFeatures(Im2gray);
                
                [f1, vpts1] = extractFeatures(Im1gray, points1);
                [f2, vpts2] = extractFeatures(Im2gray, points2);
                
                indexPairs = matchFeatures(f1, f2, 'MatchThreshold', app.similarityThreshold) ;
                sizeIndexPairs = size(indexPairs);
                matchedPoints1 = vpts1(indexPairs(1:sizeIndexPairs(1), 1));
                matchedPoints2 = vpts2(indexPairs(1:sizeIndexPairs(1), 2));
                
                figure; ax = axes;
                showMatchedFeatures(Im1gray,Im2gray,matchedPoints1,matchedPoints2,'montage','Parent',ax);
                title(ax,'HARRIS matched features');
                legend(ax, 'Matched points 1','Matched points 2');
                
            otherwise
                disp('other value')
        end
        end

        % Button pushed function: CreatesequenceButton
        function CreatesequenceButtonPushed(app, event)
        [file,path] = uigetfile('*.avi');
        if isequal(file,0)
            disp('User selected Cancel');
            return;
        else
            disp(['User selected ', fullfile(path,file)]);
        end
        fileDirectory = fullfile(path,file);
        newFolder = strcat('Sequence-', file);
        saveDirectory = fullfile(path, newFolder);
        mkdir(saveDirectory)       
        
        obj =  VideoReader(fileDirectory);
        vid = read(obj);
        frames = obj.NumFrames;

        hWaitBar = waitbar(0,'Creating frames...','Name', 'Please wait');
        for x = 1 : frames
            imwrite(vid(:,:,:,x),fullfile(saveDirectory, sprintf('Frame_%4.4d.jpg', x)))
            waitbar(x/frames)
        end
        delete(hWaitBar);
        end

        % Button pushed function: GeneratereportButton
        function GeneratereportButtonPushed(app, event)
            col_header = {'Cecha obrazu I', 'Cecha obrazu II', 'Próg', 'Deskryptor', 'Liczba wykrytych'};
            cecha1 = app.mtchPoints1;
            cecha2 = app.mtchPoints2;
            descriptor = app.selectedDescriptor;
            t = cellstr(descriptor);
            prog = app.similarityThreshold;
            
            xlswrite('save.xlsx', col_header, 'Sheet1', 'B1');
            xlswrite('save.xlsx', t, 'Sheet1', 'E1');
            xlswrite('save.xlsx', prog, 'Sheet1', 'D1');
            %for i = 1: indexPairs.Count
                
                 xlswrite('save.xlsx', cecha1.Location, 'Sheet1', 'B2');
                xlswrite('save.xlsx', cecha2.Location, 'Sheet1', 'C2');
            %end
            
            winopen('save.xlsx');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageFeatureAnalysisUIFigure = uifigure('Visible', 'off');
            app.ImageFeatureAnalysisUIFigure.Name = 'Image Feature Analysis';
            app.PicturescataloguesPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.PicturescataloguesPanel.Title = 'Pictures catalogues';
            app.ExtensionDropDownLabel = uilabel(app.PicturescataloguesPanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.PicturescataloguesPanel);
            app.ExtensionDropDown.Items = {'.jpg', '.png', '.tif', '.avi'};
            app.ExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtensionDropDownValueChanged, true);
            app.ExtensionDropDown.Value = '.jpg';
            app.ChooseonepathButton = uibutton(app.PicturescataloguesPanel, 'push');
            app.ChooseonepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseonepathButtonPushed, true);
            app.ChooseonepathButton.Text = 'Choose one path';
            app.ChoosetwopathsButton = uibutton(app.PicturescataloguesPanel, 'push');
            app.ChoosetwopathsButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetwopathsButtonPushed, true);
            app.ChoosetwopathsButton.Text = 'Choose two paths';
            app.ImagesPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes_2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.stimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.stimageEditFieldLabel.Text = '1st image';
            app.stimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.stimageEditField.ValueChangedFcn = createCallbackFcn(app, @stimageEditFieldValueChanged, true);
            app.stimageEditField.Value = 1;
            app.ndimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.ndimageEditFieldLabel.Text = '2nd image';
            app.ndimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.ndimageEditField.ValueChangedFcn = createCallbackFcn(app, @ndimageEditFieldValueChanged, true);
            app.ndimageEditField.Value = 2;
            app.numberOfFiles1Label = uilabel(app.ImagesPanel);
            app.numberOfFiles1Label.Text = '';
            app.numberOfFiles2Label = uilabel(app.ImagesPanel);
            app.numberOfFiles2Label.Text = '';
            app.OptionsPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DescriptorsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DescriptorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DescriptorsButtonGroupSelectionChanged, true);
            app.DescriptorsButtonGroup.Title = 'Descriptors';
            app.SURFButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.SURFButton.Value = true;
            app.BRISKButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.BRISKButton.Text = 'BRISK';
            app.HOGButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.FASTButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.FASTButton.Text = 'FAST';
            app.ORBButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.MSERButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.MSERButton.Text = 'MSER';
            app.HARRISButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HARRISButton.Text = 'HARRIS';
            app.ColormodelSwitchLabel = uilabel(app.OptionsPanel);
            app.ColormodelSwitchLabel.Text = 'Color model';
            app.ColormodelSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ColormodelSwitch.Items = {'RGB', 'G+B'};
            app.ColormodelSwitch.ValueChangedFcn = createCallbackFcn(app, @ColormodelSwitchValueChanged, true);
            app.ColormodelSwitch.Value = 'RGB';
            app.CompareButton = uibutton(app.OptionsPanel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.Slider = uislider(app.OptionsPanel);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.Slider.MajorTickLabels = {'1', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.GeneratereportButton = uibutton(app.OptionsPanel, 'push');
            app.GeneratereportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratereportButtonPushed, true);
            app.GeneratereportButton.Text = 'Generate report';
            app.SimiliaritythresholdLabel = uilabel(app.OptionsPanel);
            app.SimiliaritythresholdLabel.Text = 'Similiarity threshold';
            app.ThresholdLabel = uilabel(app.OptionsPanel);
            app.ThresholdLabel.Text = '1';
            app.MoviesequencecreatorPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.MoviesequencecreatorPanel.Title = 'Movie sequence creator';
            app.CreatesequenceButton = uibutton(app.MoviesequencecreatorPanel, 'push');
            app.CreatesequenceButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesequenceButtonPushed, true);
            app.CreatesequenceButton.Text = 'Create sequence';
            % Show the figure after all components are created
            app.ImageFeatureAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFeaturesAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageFeatureAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageFeatureAnalysisUIFigure)
        end
    end
end